classdef CAP_app_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AutomatedCAPScoringUIFigure     matlab.ui.Figure
        Panel                           matlab.ui.container.Panel
        CFAremovalCheckBox              matlab.ui.control.CheckBox
        TextSelectAgeGroup              matlab.ui.control.Label
        Scoring                         matlab.ui.container.ButtonGroup
        Aphases                         matlab.ui.control.RadioButton
        CAP                             matlab.ui.control.RadioButton
        TextSelectScoring               matlab.ui.control.Label
        AgeGroup                        matlab.ui.container.ButtonGroup
        Adults                          matlab.ui.control.RadioButton
        Children                        matlab.ui.control.RadioButton
        TextDescription                 matlab.ui.control.Label
        Logo                            matlab.ui.control.Image
        TextPublication                 matlab.ui.control.Label
        TextInventors                   matlab.ui.control.Label
        TextContact                     matlab.ui.control.Label
        TextPublicationHeader           matlab.ui.control.Label
        TextInventorsHeader             matlab.ui.control.Label
        TextDescriptionHeader           matlab.ui.control.Label
        ContactHeader                   matlab.ui.control.Label
        Title                           matlab.ui.control.Label
        CreateannotationfilesCheckBox   matlab.ui.control.CheckBox
        StartscoringButton              matlab.ui.control.Button
        LogOutput                       matlab.ui.control.TextArea
        BrowseButton                    matlab.ui.control.Button
        FilepathEditField               matlab.ui.control.EditField
        TextLogOutput                   matlab.ui.control.Label
        TextSelectOutput                matlab.ui.control.Label
        TextFilePath                    matlab.ui.control.Label
        CreateplotsCheckBox             matlab.ui.control.CheckBox
        CreateexcelspreadsheetCheckBox  matlab.ui.control.CheckBox
        CalculatederivationsCheckBox    matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        Files % Description
        Folder % Description
        ChannelApp % Description
        DerivationsApp % Description
        EEG
        header
        Channels
        ProgressPatch
        ProgressText
        ProgressBar
        Flags
        Report
        ECGlabel
    end
       
    methods (Access = private)
              

    end
    
    methods (Access = public)
        
        function getChannels(app, channels, SubApp)
            app.Channels = channels;
            delete(SubApp);
            n = length(app.Files); 
            app.LogOutput.Value = [app.LogOutput.Value;sprintf("#####\n%d subjects will be scored. Loading each edf file will take some time.\nThe progress bar below will show an update on the current status.\nA full scoring report will be provided at the end of the analysis.",n)];
            pause(.5);
            % Initialize report
            app.Report = struct;
            app.Report.Success = 0;
            app.Report.WrongChannel = 0;
            app.Report.NoStageScoring = 0;
            app.Report.NoECG = 0;
            app.Report.Error = 0;
            app.Report.ErrorFiles = {};
            app.ProgressPatch.XData = [0 0 0 0]; 
            app.ProgressText.String = sprintf('%.0f%%',round(0*100)); 
            drawnow %update graphics
            if app.CalculatederivationsCheckBox.Value
                derivations = cellfun(@(x) strsplit(x,'-'), app.Channels,'UniformOutput',false);
                for i = 1:n
                    app.Report.Stop = false;
                    CFA_flag = app.CFAremovalCheckBox.Value;
                    if CFA_flag
                        app.header = edfread(strcat(app.Folder, app.Files{i}));
                        % Find ECG channel
                        [app.ECGlabel, CFA_flag, app.Report] = findECGchannel(app.header, CFA_flag, app.Report);
                        channel_list = horzcat(derivations{:});
                        channel_list{end+1} = app.ECGlabel;
                    else
                        channel_list = horzcat(derivations{:});
                    end
                    try
                        app.EEG = SignalEEG(app.Folder, app.Files{i}, channel_list{1}, 'True', channel_list);
                    catch
                        app.Report.Error = app.Report.Error + 1;
                        app.Report.NoStageScoring = app.Report.NoStageScoring + 1;
                        app.Report.ErrorFiles{end+1} = app.Files{i};
                        app.Report.Stop = false;
                    end
                    for j = 1 : length(derivations)
                        if ~app.Report.Stop
                            [input{j}, app.Report, app.EEG, CFA_flag] = preProcessingDerivation(derivations{j}, app.EEG, CFA_flag, app.Report);
                        end
                    end
                    if ~app.Report.Stop
                        [app.Report] = CAPclassification(input, app.EEG, i, app.Flags, app.Report);
                    end
                    % update patch size and percentage text
                    app.ProgressPatch.XData = [0 i/n i/n 0]; 
                    app.ProgressText.String = sprintf('%.0f%%',round(i/n*100)); 
                    drawnow %update graphics
                    app.EEG = [];
                end
                app.LogOutput.Value = [app.LogOutput.Value;sprintf("#####\n%d subjects were successfully scored.\n%d subjects could not be scored.\n%d subjects did not have a sleep staging annotation file.\n%d subjects did not contain the selected derivations.\n%d subjects did not contain an ECG for CFA removal.\nFollowing subjects could not be scored: %s",app.Report.Success, app.Report.Error, app.Report.NoStageScoring, app.Report.WrongChannel, app.Report.NoECG, strjoin(app.Report.ErrorFiles,', '))];
            else
                for i = 1:n
                    app.Report.Stop = false;
                    CFA_flag = app.CFAremovalCheckBox.Value;
                    if CFA_flag
                        app.header = edfread(strcat(app.Folder, app.Files{i}));
                        % Find ECG channel
                        [app.ECGlabel, CFA_flag, app.Report] = findECGchannel(app.header, CFA_flag, app.Report);
                        channel_list = app.Channels;
                        channel_list{end+1} = app.ECGlabel;
                    else
                        channel_list = app.Channels;
                    end
                    try
                        app.EEG = SignalEEG(app.Folder, app.Files{i}, channel_list{1}, 'True', channel_list);
                    catch
                        app.Report.Error = app.Report.Error + 1;
                        app.Report.NoStageScoring = app.Report.NoStageScoring + 1;
                        app.Report.ErrorFiles{end+1} = app.Files{i};
                        app.Report.Stop = false;
                    end
                    for j = 1 : length(app.Channels)
                        if ~app.Report.Stop
                            [input{j}, app.Report, app.EEG, CFA_flag] = preProcessingChannel(app.Channels{j}, app.EEG, CFA_flag, app.Report);
                        end
                    end
                    if ~app.Report.Stop
                        [app.Report] = CAPclassification(input, app.EEG, i, app.Flags, app.Report);
                    end
                    % update patch size and percentage text
                    app.ProgressPatch.XData = [0 i/n i/n 0]; 
                    app.ProgressText.String = sprintf('%.0f%%',round(i/n*100)); 
                    drawnow %update graphics
                    app.EEG = [];
                end
                app.LogOutput.Value = [app.LogOutput.Value;sprintf("#####\n%d subjects were successfully scored.\n%d subjects could not be scored.\n%d subjects did not have a sleep staging annotation file.\n%d subjects did not contain the selected channels.\n%d subjects did not contain an ECG for CFA removal.\nFollowing subjects could not be scored: %s",app.Report.Success, app.Report.Error, app.Report.NoStageScoring, app.Report.WrongChannel, app.Report.NoECG, strjoin(app.Report.ErrorFiles,', '))];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %app.Logo.ImageSource = fullfile(ctfroot, 'logo2.jpg');
            % Set up the progress bar axis           
            app.ProgressBar = subplot(1,1,1,'Parent',app.Panel);
            
            app.ProgressBar.Position=[.07 .03 .625 .05];
            app.ProgressBar.Box='on';
            app.ProgressBar.XTick=[];
            app.ProgressBar.YTick=[];
            app.ProgressBar.Color=[0.85 0.85 0.85];
            app.ProgressBar.XAxis.Limits=[0,1];
            app.ProgressBar.YAxis.Limits=[0,1];
            title(app.ProgressBar,'Progress',"Color",[1 1 1],"FontName","Open Sans","FontSize",9,"FontWeight","normal");
            % Create empty patch that will be updated
            app.ProgressPatch = patch(app.ProgressBar,[0 0 0 0],[0 0 1 1],[0.831 0 0]); %greenyellow
            % Create the percent-complete text that will be updated
            app.ProgressText = text(app.ProgressBar,1,1,'0%','VerticalAlignment','bottom','HorizontalAlignment','right',"Color",[1 1 1],"FontName","Open Sans","FontSize",7);         
            
        end

        % Value changed function: LogOutput
        function LogOutputValueChanged(app, event)
            value = app.LogOutput.Value;

        end

        % Button pushed function: StartscoringButton
        function StartscoringButtonPushed(app, event)
            app.Flags.AgeGroup = app.AgeGroup.SelectedObject.Text;
            app.Flags.Scoring = app.Scoring.SelectedObject.Text;
            app.Flags.Excel = app.CreateexcelspreadsheetCheckBox.Value;
            app.Flags.Text = app.CreateannotationfilesCheckBox.Value;
            app.Flags.Plot = app.CreateplotsCheckBox.Value;
            app.Flags.Files = false;
            if ~isempty(app.FilepathEditField.Value)
                if  ~strcmp(app.Folder(end),'\')
                    app.Folder = [app.Folder, '\'];
                    app.FilepathEditField.Value = app.Folder;
                end
                if app.Flags.Excel || app.Flags.Plot || app.Flags.Text
                    app.LogOutput.Value = [app.LogOutput.Value;sprintf("#####\nLoading channels ...")];
                    pause(.5)
                    try
                        [app.header, ~] = edfread(strcat(app.Folder, app.Files{1}));
                        app.Flags.Files = true;
                    catch
                        uialert(app.AutomatedCAPScoringUIFigure,'Could not find folder or files!','File selection error','Icon','warning');   
                    end
                    if app.Flags.Files
                        if app.CalculatederivationsCheckBox.Value
                            % Open the options dialog and pass inputs
                            app.DerivationsApp = CAP_derivationselection(app, app.header.label);
                        else
                            % Open the options dialog and pass inputs
                            app.ChannelApp = CAP_channelselection(app, app.header.label);
                        end
                    end
                else
                    uialert(app.AutomatedCAPScoringUIFigure,'No output selected!','Output error','Icon','warning');
                end
            else
                uialert(app.AutomatedCAPScoringUIFigure,'No files/folder selected!','File selection error','Icon','warning');
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [app.Files,app.Folder]=uigetfile({'*.edf';'*.rec'},'File Selector','MultiSelect', 'on');
            drawnow;
            figure(app.AutomatedCAPScoringUIFigure);
            if ischar(app.Folder)
                if ~iscell(app.Files)
                    app.Files = {app.Files};
                end
                app.FilepathEditField.Value = app.Folder;
            end
        end

        % Value changed function: FilepathEditField
        function FilepathEditFieldValueChanged(app, event)
            app.Folder = app.FilepathEditField.Value;
            if  ~strcmp(app.Folder(end),'\')
                app.Folder = [app.Folder, '\'];
                app.FilepathEditField.Value = app.Folder;
            end    
            file_list = dir(strcat(app.Folder,'*.edf'));
            if isempty(file_list)
                file_list = dir(strcat(app.Folder,'*.rec'));
            end
            app.Files = {file_list(1:length(file_list)).name};
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AutomatedCAPScoringUIFigure = uifigure('Visible', 'off');
            app.AutomatedCAPScoringUIFigure.Name = 'Automated CAP Scoring';
            app.Panel = uipanel(app.AutomatedCAPScoringUIFigure);
            app.CalculatederivationsCheckBox = uicheckbox(app.Panel);
            app.CalculatederivationsCheckBox.Text = 'Calculate derivations';
            app.CreateexcelspreadsheetCheckBox = uicheckbox(app.Panel);
            app.CreateexcelspreadsheetCheckBox.Text = 'Create excel spreadsheet';
            app.CreateexcelspreadsheetCheckBox.Value = true;
            app.CreateplotsCheckBox = uicheckbox(app.Panel);
            app.CreateplotsCheckBox.Text = 'Create plots';
            app.TextFilePath = uilabel(app.Panel);
            app.TextFilePath.Text = 'Select folder or file/s for CAP analysis:';
            app.TextSelectOutput = uilabel(app.Panel);
            app.TextSelectOutput.Text = 'Select output:';
            app.TextLogOutput = uilabel(app.Panel);
            app.TextLogOutput.Text = 'Log output:';
            app.FilepathEditField = uieditfield(app.Panel, 'text');
            app.FilepathEditField.ValueChangedFcn = createCallbackFcn(app, @FilepathEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.Panel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.LogOutput = uitextarea(app.Panel);
            app.LogOutput.ValueChangedFcn = createCallbackFcn(app, @LogOutputValueChanged, true);
            app.LogOutput.Editable = 'off';
            app.StartscoringButton = uibutton(app.Panel, 'push');
            app.StartscoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartscoringButtonPushed, true);
            app.StartscoringButton.Text = 'Start scoring';
            app.CreateannotationfilesCheckBox = uicheckbox(app.Panel);
            app.CreateannotationfilesCheckBox.Text = 'Create annotation files';
            app.Title = uilabel(app.Panel);
            app.Title.Text = 'Automated CAP Scoring';
            app.ContactHeader = uilabel(app.Panel);
            app.ContactHeader.Text = {'Contact: '; ''};
            app.TextDescriptionHeader = uilabel(app.Panel);
            app.TextDescriptionHeader.Text = 'Description:';
            app.TextInventorsHeader = uilabel(app.Panel);
            app.TextInventorsHeader.Text = 'Inventors:';
            app.TextPublicationHeader = uilabel(app.Panel);
            app.TextPublicationHeader.Text = 'Publication:';
            app.TextContact = uilabel(app.Panel);
            app.TextContact.Text = 'simon.hartmann@adelaide.edu.au';
            app.TextInventors = uilabel(app.Panel);
            app.TextInventors.Text = 'Simon Hartmann & Mathias Baumert';
            app.TextPublication = uilabel(app.Panel);
            app.TextPublication.Text = {'S. Hartmann and M. Baumert, “Automatic'; 'A-Phase Detection of Cyclic Alternating '; 'Patterns in Sleep Using Dynamic Temporal'; 'Information,” IEEE Trans. Neural Syst. '; 'Rehabil. Eng., vol. 27, no. 9,'; 'pp. 1695–1703, 2019.'};
            app.Logo = uiimage(app.Panel);
            app.Logo.ImageSource = 'logo2.jpg';
            app.TextDescription = uilabel(app.Panel);
            app.TextDescription.Text = {'A novel, automated system for the '; 'classification of activation phases (A-phases) '; 'and their subtypes in cyclic alternating '; 'pattern (CAP) sequences during sleep '};
            app.AgeGroup = uibuttongroup(app.Panel);
            app.Children = uiradiobutton(app.AgeGroup);
            app.Children.Text = 'Children';
            app.Children.Value = true;
            app.Adults = uiradiobutton(app.AgeGroup);
            app.Adults.Text = ' Adults';
            app.TextSelectScoring = uilabel(app.Panel);
            app.TextSelectScoring.Text = 'Select scoring:';
            app.Scoring = uibuttongroup(app.Panel);
            app.CAP = uiradiobutton(app.Scoring);
            app.CAP.Text = 'CAP';
            app.CAP.Value = true;
            app.Aphases = uiradiobutton(app.Scoring);
            app.Aphases.Text = 'A-phases';
            app.TextSelectAgeGroup = uilabel(app.Panel);
            app.TextSelectAgeGroup.Text = 'Select age group:';
            app.CFAremovalCheckBox = uicheckbox(app.Panel);
            app.CFAremovalCheckBox.Text = 'CFA removal';
            % Show the figure after all components are created
            app.AutomatedCAPScoringUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAP_app_v1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AutomatedCAPScoringUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AutomatedCAPScoringUIFigure)
        end
    end
end