classdef CAP_channelselection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectchannelsUIFigure  matlab.ui.Figure
        AvailableChannels       matlab.ui.control.ListBox
        OkButton                matlab.ui.control.Button
        SelectButton            matlab.ui.control.Button
        AvailablechannelsLabel  matlab.ui.control.Label
        SelectedchannelsLabel   matlab.ui.control.Label
        Logo                    matlab.ui.control.Image
        RemoveButton            matlab.ui.control.Button
        SelectedChannels        matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, channellist)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.AvailableChannels.Items = channellist;
            app.AvailableChannels.Value = channellist{1};
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.SelectedChannels.Items = [app.SelectedChannels.Items, app.AvailableChannels.Value];
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Call main app's public function
            getChannels(app.CallingApp, app.SelectedChannels.Items, app);
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.SelectedChannels.Items = setdiff(app.SelectedChannels.Items, app.SelectedChannels.Value,'stable');
        end

        % Close request function: SelectchannelsUIFigure
        function SelectchannelsUIFigureCloseRequest(app, event)
            delete(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectchannelsUIFigure = uifigure('Visible', 'off');
            app.SelectchannelsUIFigure.Name = 'Select channels';
            app.SelectchannelsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectchannelsUIFigureCloseRequest, true);
            app.AvailableChannels = uilistbox(app.SelectchannelsUIFigure);
            app.AvailableChannels.Multiselect = 'on';
            app.AvailableChannels.Value = {'Item 1'};
            app.OkButton = uibutton(app.SelectchannelsUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SelectButton = uibutton(app.SelectchannelsUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Add';
            app.AvailablechannelsLabel = uilabel(app.SelectchannelsUIFigure);
            app.AvailablechannelsLabel.Text = 'Available channels';
            app.SelectedchannelsLabel = uilabel(app.SelectchannelsUIFigure);
            app.SelectedchannelsLabel.Text = 'Selected channels';
            app.Logo = uiimage(app.SelectchannelsUIFigure);
            app.Logo.ImageSource = 'logo2.jpg';
            app.RemoveButton = uibutton(app.SelectchannelsUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SelectedChannels = uilistbox(app.SelectchannelsUIFigure);
            app.SelectedChannels.Items = {};
            app.SelectedChannels.Multiselect = 'on';
            app.SelectedChannels.Value = {};
            % Show the figure after all components are created
            app.SelectchannelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAP_channelselection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectchannelsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectchannelsUIFigure)
        end
    end
end