classdef CAP_derivationselection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectderivationsUIFigure  matlab.ui.Figure
        AvailableChannels          matlab.ui.control.ListBox
        OkButton                   matlab.ui.control.Button
        SelectButton               matlab.ui.control.Button
        AvailablechannelsLabel     matlab.ui.control.Label
        SelectedderivationsLabel   matlab.ui.control.Label
        Logo                       matlab.ui.control.Image
        RemoveButton               matlab.ui.control.Button
        SelectedChannels           matlab.ui.control.ListBox
        AvailableChannels_2        matlab.ui.control.ListBox
        Label                      matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, channellist)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            app.AvailableChannels.Items = channellist;
            app.AvailableChannels.Value = channellist{1};
            
            app.AvailableChannels_2.Items = channellist;
            app.AvailableChannels_2.Value = channellist{1};
            
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.SelectedChannels.Items = [app.SelectedChannels.Items, strcat(app.AvailableChannels.Value,'-',app.AvailableChannels_2.Value)];
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % Call main app's public function
            getChannels(app.CallingApp, app.SelectedChannels.Items, app);
            % Delete the dialog box
            delete(app)
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            app.SelectedChannels.Items = setdiff(app.SelectedChannels.Items, app.SelectedChannels.Value,'stable');
        end

        % Close request function: SelectderivationsUIFigure
        function SelectderivationsUIFigureCloseRequest(app, event)
            delete(app)          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectderivationsUIFigure = uifigure('Visible', 'off');
            app.SelectderivationsUIFigure.Name = 'Select derivations';
            app.SelectderivationsUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectderivationsUIFigureCloseRequest, true);
            app.AvailableChannels = uilistbox(app.SelectderivationsUIFigure);
            app.OkButton = uibutton(app.SelectderivationsUIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.SelectButton = uibutton(app.SelectderivationsUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Add';
            app.AvailablechannelsLabel = uilabel(app.SelectderivationsUIFigure);
            app.AvailablechannelsLabel.Text = 'Available channels';
            app.SelectedderivationsLabel = uilabel(app.SelectderivationsUIFigure);
            app.SelectedderivationsLabel.Text = 'Selected derivations';
            app.Logo = uiimage(app.SelectderivationsUIFigure);
            app.Logo.ImageSource = 'logo2.jpg';
            app.RemoveButton = uibutton(app.SelectderivationsUIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.SelectedChannels = uilistbox(app.SelectderivationsUIFigure);
            app.SelectedChannels.Items = {};
            app.SelectedChannels.Multiselect = 'on';
            app.SelectedChannels.Value = {};
            app.AvailableChannels_2 = uilistbox(app.SelectderivationsUIFigure);
            app.Label = uilabel(app.SelectderivationsUIFigure);
            app.Label.Text = '-';
            % Show the figure after all components are created
            app.SelectderivationsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CAP_derivationselection(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectderivationsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectderivationsUIFigure)
        end
    end
end